classdef CageLab < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CageLabUIFigure                 matlab.ui.Figure
		GridLayoutMAIN                  matlab.ui.container.GridLayout
		GridLayoutLOG                   matlab.ui.container.GridLayout
		Image                           matlab.ui.control.Image
		Log                             matlab.ui.control.TextArea
		TabGroup                        matlab.ui.container.TabGroup
		MainTab                         matlab.ui.container.Tab
		GridLayout                      matlab.ui.container.GridLayout
		GlobalSettingsPanel             matlab.ui.container.Panel
		GridLayout2                     matlab.ui.container.GridLayout
		TouchScreenDefaultsPanel        matlab.ui.container.Panel
		GridLayout10                    matlab.ui.container.GridLayout
		VerboseCheckBox                 matlab.ui.control.CheckBox
		TouchDeviceNameEditField        matlab.ui.control.EditField
		TouchDeviceNameEditFieldLabel   matlab.ui.control.Label
		NegationbufferdegEditField      matlab.ui.control.NumericEditField
		NegationbufferdegEditFieldLabel  matlab.ui.control.Label
		ExclusionZoneEditField          matlab.ui.control.EditField
		ExclusionZoneEditFieldLabel     matlab.ui.control.Label
		IgnoreExtraTouchesCheckBox      matlab.ui.control.CheckBox
		DrainMultipleEventsCheckBox     matlab.ui.control.CheckBox
		StrictmodeCheckBox              matlab.ui.control.CheckBox
		NegateTouchWindowCheckBox       matlab.ui.control.CheckBox
		TouchDeviceIDEditField          matlab.ui.control.NumericEditField
		TouchDeviceIDEditFieldLabel     matlab.ui.control.Label
		PumpmsEditField                 matlab.ui.control.NumericEditField
		PumpmsEditFieldLabel            matlab.ui.control.Label
		AudioDeviceEditField            matlab.ui.control.EditField
		AudioDeviceEditFieldLabel       matlab.ui.control.Label
		RewardPumpSwitch                matlab.ui.control.Switch
		RewardPumpLabel                 matlab.ui.control.Label
		DummyTouchSwitch                matlab.ui.control.Switch
		DummyTouchLabel                 matlab.ui.control.Label
		IncorrectBeepHzEditField        matlab.ui.control.NumericEditField
		IncorrectBeepHzEditFieldLabel   matlab.ui.control.Label
		CorrectBeepHzEditField          matlab.ui.control.NumericEditField
		Label                           matlab.ui.control.Label
		PunishmentTimeOutEditField      matlab.ui.control.NumericEditField
		PunishmentTimeOutEditFieldLabel  matlab.ui.control.Label
		AudioVolumeEditField            matlab.ui.control.NumericEditField
		AudioVolumeEditFieldLabel       matlab.ui.control.Label
		folderEditField                 matlab.ui.control.EditField
		ChangeResourceFolderButton      matlab.ui.control.Button
		PushtoALYXSwitch                matlab.ui.control.Switch
		PushtoALYXLabel                 matlab.ui.control.Label
		AudioSwitch                     matlab.ui.control.Switch
		AudioLabel                      matlab.ui.control.Label
		DebugModeSwitch                 matlab.ui.control.Switch
		DebugModeLabel                  matlab.ui.control.Label
		SmartBackgroundSwitch           matlab.ui.control.Switch
		SmartBackgroundLabel            matlab.ui.control.Label
		CageLabRemoteControlPanel       matlab.ui.container.Panel
		GridLayout4                     matlab.ui.container.GridLayout
		Button_2                        matlab.ui.control.Button
		GridLayout18                    matlab.ui.container.GridLayout
		EnableSleepButton               matlab.ui.control.Button
		DisableSleepButton              matlab.ui.control.Button
		ShowDesktopButton               matlab.ui.control.Button
		PowerDownButton                 matlab.ui.control.Button
		ResetOBSButton                  matlab.ui.control.Button
		ResetMATLABButton               matlab.ui.control.Button
		HideDesktopButton               matlab.ui.control.Button
		WakeUpButton                    matlab.ui.control.Button
		AlyxLink                        matlab.ui.control.Hyperlink
		CustomEditField                 matlab.ui.control.EditField
		RmIP                            matlab.ui.control.Button
		AddIP                           matlab.ui.control.Button
		RemoteServerPortEditFieldLabel_2  matlab.ui.control.Label
		IPAddress                       matlab.ui.control.DropDown
		RemoteCommand                   matlab.ui.control.ListBox
		RunCommandButton                matlab.ui.control.Button
		PingButton                      matlab.ui.control.Button
		RemoteControlSwitch             matlab.ui.control.Switch
		RemoteControlSwitchLabel        matlab.ui.control.Label
		RemoteDevicePortEditField       matlab.ui.control.EditField
		RemoteDevicePortEditFieldLabel  matlab.ui.control.Label
		PixelDensitypxcmEditField       matlab.ui.control.NumericEditField
		PixelDensitypxcmEditFieldLabel  matlab.ui.control.Label
		BackgroundColorEditField        matlab.ui.control.EditField
		BackgroundColorEditFieldLabel   matlab.ui.control.Label
		DistancecmEditField             matlab.ui.control.NumericEditField
		DistancecmEditFieldLabel        matlab.ui.control.Label
		ScreenEditField                 matlab.ui.control.NumericEditField
		ScreenEditFieldLabel            matlab.ui.control.Label
		AlyxTab                         matlab.ui.container.Tab
		GridLayout11                    matlab.ui.container.GridLayout
		ALYXInterfacePanel              matlab.ui.container.Panel
		AlyxLayout                      matlab.ui.container.GridLayout
		ChangePWButton                  matlab.ui.control.Button
		PasswordReset                   matlab.ui.control.DropDown
		SessionInformationLabel         matlab.ui.control.Label
		AlyxAddWater                    matlab.ui.control.Button
		SubjectWater                    matlab.ui.control.EditField
		AlyxAddWeight                   matlab.ui.control.Button
		SubjectWeight                   matlab.ui.control.EditField
		QueryAlyxDatabaseLabel          matlab.ui.control.Label
		text4554_6                      matlab.ui.control.Label
		OKLabLocation                   matlab.ui.control.EditField
		text209_2                       matlab.ui.control.Label
		OKLabName                       matlab.ui.control.EditField
		text4554_5                      matlab.ui.control.Label
		OKResearcher                    matlab.ui.control.EditField
		text209                         matlab.ui.control.Label
		OKSubject                       matlab.ui.control.EditField
		ProcedureLabel                  matlab.ui.control.Label
		alyxUser                        matlab.ui.control.EditField
		alyxIP                          matlab.ui.control.EditField
		OKAlyxTaskProtocol              matlab.ui.control.EditField
		TaskProtocolEditFieldLabel      matlab.ui.control.Label
		text4554_4                      matlab.ui.control.Label
		OKAlyxBrainRegion               matlab.ui.control.DropDown
		text4554_3                      matlab.ui.control.Label
		OKAlyxProject                   matlab.ui.control.DropDown
		OKAlyxProcedure                 matlab.ui.control.DropDown
		OKAlyxRequest                   matlab.ui.control.EditField
		OKAlyxLOGINButton               matlab.ui.control.Button
		OKAlyxLOGOUTButton              matlab.ui.control.Button
		OKAlyxRequestButton             matlab.ui.control.Button
		OKAlyxResults                   matlab.ui.control.Table
		text4554_2                      matlab.ui.control.Label
		text4554                        matlab.ui.control.Label
		TouchTab                        matlab.ui.container.Tab
		GridLayout12                    matlab.ui.container.GridLayout
		AutomatedTouchTrainingPanel     matlab.ui.container.Panel
		GridLayout9                     matlab.ui.container.GridLayout
		MaxPhasesStepBackEditField      matlab.ui.control.NumericEditField
		MaxPhasesStepBackEditFieldLabel  matlab.ui.control.Label
		StatusButton                    matlab.ui.control.Button
		RandomrewardsonlyoccurinPhase1to3andthisencouragesthemonkeytocomeforwardsandengagewithCageLabIfthemonkeyisnotengagingeveryXsecondsarewardwillbegivenwithYprobabilityLabel  matlab.ui.control.Label
		PercentCorrectThresholdEditField  matlab.ui.control.NumericEditField
		PercentCorrectThresholdEditFieldLabel  matlab.ui.control.Label
		StepBackNEditField              matlab.ui.control.NumericEditField
		StepBackNEditFieldLabel         matlab.ui.control.Label
		StopButton_5                    matlab.ui.control.Button
		STARTButton                     matlab.ui.control.Button
		TargetTypeDropDown              matlab.ui.control.DropDown
		TargetTypeDropDownLabel         matlab.ui.control.Label
		DifficultyDropDown              matlab.ui.control.DropDown
		DifficultyDropDownLabel         matlab.ui.control.Label
		TrialTimeEditField              matlab.ui.control.NumericEditField
		TrialTimeEditFieldLabel         matlab.ui.control.Label
		TargetDiscColorEditField        matlab.ui.control.EditField
		TargetDiscColorEditFieldLabel   matlab.ui.control.Label
		RandomRewardProbabilityEditField  matlab.ui.control.NumericEditField
		RandomRewardProbabilityEditFieldLabel  matlab.ui.control.Label
		RandomRewardTimerEditField      matlab.ui.control.NumericEditField
		RandomRewardTimerEditFieldLabel  matlab.ui.control.Label
		MinimumTargetSizeEditField      matlab.ui.control.NumericEditField
		MinimumTargetSizeEditFieldLabel  matlab.ui.control.Label
		MaximumTargetSizeEditField      matlab.ui.control.NumericEditField
		MaximumTargetSizeEditFieldLabel  matlab.ui.control.Label
		StepForwardNEditField           matlab.ui.control.NumericEditField
		StepForwardNEditFieldLabel      matlab.ui.control.Label
		Phase120EditField               matlab.ui.control.NumericEditField
		Phase120EditFieldLabel          matlab.ui.control.Label
		MTSTab                          matlab.ui.container.Tab
		GridLayout13                    matlab.ui.container.GridLayout
		MatchToSampleMTSPanel           matlab.ui.container.Panel
		GridLayout9_2                   matlab.ui.container.GridLayout
		StatusButton_2                  matlab.ui.control.Button
		TargetHoldTimeEditField         matlab.ui.control.NumericEditField
		TargetHoldTimeEditFieldLabel    matlab.ui.control.Label
		InitiationHoldTimeEditField     matlab.ui.control.NumericEditField
		InitiationHoldTimeEditFieldLabel  matlab.ui.control.Label
		DistractorYPositionEditField    matlab.ui.control.NumericEditField
		DistractorYPositionEditFieldLabel  matlab.ui.control.Label
		SampleYPositionEditField        matlab.ui.control.NumericEditField
		SampleYPositionEditFieldLabel   matlab.ui.control.Label
		InitiationPointSizeEditField_2  matlab.ui.control.NumericEditField
		InitiationPointSizeEditField_2Label  matlab.ui.control.Label
		InitiationPointColourEditField_2  matlab.ui.control.EditField
		InitiationPointColourEditField_2Label  matlab.ui.control.Label
		InitiationPointPositionxyEditField_2  matlab.ui.control.EditField
		InitiationPointPositionxyEditField_2Label  matlab.ui.control.Label
		StopButton_6                    matlab.ui.control.Button
		STARTMTSButton                  matlab.ui.control.Button
		ObjectTypeDropDown              matlab.ui.control.DropDown
		ObjectTypeDropDownLabel         matlab.ui.control.Label
		TaskStageDropDown               matlab.ui.control.DropDown
		TaskStageDropDownLabel          matlab.ui.control.Label
		TrialTimeEditField_2            matlab.ui.control.NumericEditField
		TrialTimeEditField_2Label       matlab.ui.control.Label
		ObjectSeparationEditField       matlab.ui.control.NumericEditField
		ObjectSeparationEditFieldLabel  matlab.ui.control.Label
		ObjectSizeEditField             matlab.ui.control.NumericEditField
		ObjectSizeEditFieldLabel        matlab.ui.control.Label
		NumberofdistractorsEditField    matlab.ui.control.NumericEditField
		NumberofdistractorsEditFieldLabel  matlab.ui.control.Label
		DMTSTab                         matlab.ui.container.Tab
		GridLayout14                    matlab.ui.container.GridLayout
		DelayedMatchToSampleMTSPanel    matlab.ui.container.Panel
		GridLayout9_5                   matlab.ui.container.GridLayout
		StatusButton_3                  matlab.ui.control.Button
		TargetHoldTimeEditField_2       matlab.ui.control.NumericEditField
		TargetHoldTimeEditField_2Label  matlab.ui.control.Label
		InitiationHoldTimeEditField_2   matlab.ui.control.NumericEditField
		InitiationHoldTimeEditField_2Label  matlab.ui.control.Label
		SampleDisplayTimeEditField      matlab.ui.control.EditField
		SampleDisplayTimeEditFieldLabel  matlab.ui.control.Label
		DelayDistractorsSwitch          matlab.ui.control.Switch
		DelayDistractorsSwitchLabel     matlab.ui.control.Label
		DelayTimeEditField              matlab.ui.control.EditField
		DelayTimeEditFieldLabel         matlab.ui.control.Label
		DistractorYPositionEditField_2  matlab.ui.control.NumericEditField
		DistractorYPositionEditField_2Label  matlab.ui.control.Label
		SampleYPositionEditField_2      matlab.ui.control.NumericEditField
		SampleYPositionEditField_2Label  matlab.ui.control.Label
		InitiationPointSizeEditField_3  matlab.ui.control.NumericEditField
		InitiationPointSizeEditField_3Label  matlab.ui.control.Label
		InitiationPointColourEditField_3  matlab.ui.control.EditField
		InitiationPointColourEditField_3Label  matlab.ui.control.Label
		InitiationPointPositionxyEditField_3  matlab.ui.control.EditField
		InitiationPointPositionxyEditField_3Label  matlab.ui.control.Label
		StopButton_9                    matlab.ui.control.Button
		STARTDMTSButton                 matlab.ui.control.Button
		ObjectTypeDropDown_4            matlab.ui.control.DropDown
		ObjectTypeDropDown_4Label       matlab.ui.control.Label
		TaskStageDropDown_4             matlab.ui.control.DropDown
		TaskStageDropDown_4Label        matlab.ui.control.Label
		TrialTimeEditField_8            matlab.ui.control.NumericEditField
		TrialTimeEditField_8Label       matlab.ui.control.Label
		ObjectSeparationEditField_2     matlab.ui.control.NumericEditField
		ObjectSeparationEditField_2Label  matlab.ui.control.Label
		ObjectSizeEditField_3           matlab.ui.control.NumericEditField
		ObjectSizeEditField_3Label      matlab.ui.control.Label
		NumberofdistractorsEditField_2  matlab.ui.control.NumericEditField
		NumberofdistractorsEditField_2Label  matlab.ui.control.Label
		PacmanTask                      matlab.ui.container.Tab
		GridLayout15                    matlab.ui.container.GridLayout
		PacmanTaskPMTPanel              matlab.ui.container.Panel
		GridLayout9_6                   matlab.ui.container.GridLayout
		NotethePacmantaskusesitsowndisplaysettingsandsotheMaingeneraldisplaysettingsdonotapplytothistaskYoumusteditthePacmancodeitselftomakeadjustmentsforthemomentThisinterfaceallowsyoutolaunchthePacmantaskandsetthemaptobetestedandalsoconfiguretheAlyxdatasettingsLabel  matlab.ui.control.Label
		StatusButton_5                  matlab.ui.control.Button
		StopButton_10                   matlab.ui.control.Button
		STARTPMTButton                  matlab.ui.control.Button
		MapDropDown                     matlab.ui.control.DropDown
		MapLabel                        matlab.ui.control.Label
		GoukiCATTab                     matlab.ui.container.Tab
		GridLayout16                    matlab.ui.container.GridLayout
		DragCategorisationDCATPanel     matlab.ui.container.Panel
		GridLayout9_4                   matlab.ui.container.GridLayout
		StatusButton_4                  matlab.ui.control.Button
		StopButton_8                    matlab.ui.control.Button
		InitiationPointSizeEditField    matlab.ui.control.NumericEditField
		InitiationPointSizeEditFieldLabel  matlab.ui.control.Label
		ObjectSizeEditField_2           matlab.ui.control.NumericEditField
		ObjectSizeEditField_2Label      matlab.ui.control.Label
		TargetPosition2EditField        matlab.ui.control.EditField
		TargetPosition2EditFieldLabel   matlab.ui.control.Label
		TargetPosition1EditField        matlab.ui.control.EditField
		TargetPosition1EditFieldLabel   matlab.ui.control.Label
		InitiationPointColourEditField  matlab.ui.control.EditField
		InitiationPointColourEditFieldLabel  matlab.ui.control.Label
		STARTDCATButton                 matlab.ui.control.Button
		ObjectTypeDropDown_3            matlab.ui.control.DropDown
		ObjectTypeDropDown_3Label       matlab.ui.control.Label
		TaskStageDropDown_3             matlab.ui.control.DropDown
		TaskStageDropDown_3Label        matlab.ui.control.Label
		TrialTimeEditField_7            matlab.ui.control.NumericEditField
		TrialTimeEditField_7Label       matlab.ui.control.Label
		InitiationPointPositionxyEditField  matlab.ui.control.EditField
		InitiationPointPositionxyEditFieldLabel  matlab.ui.control.Label
		InterTrialIntervalEditField     matlab.ui.control.NumericEditField
		InterTrialIntervalEditFieldLabel  matlab.ui.control.Label
		ICATab                          matlab.ui.container.Tab
		GridLayout17                    matlab.ui.container.GridLayout
		PatchForagingTab                matlab.ui.container.Tab
		GridLayout17_2                  matlab.ui.container.GridLayout
		ThresholdsTab                   matlab.ui.container.Tab
		GridLayout17_3                  matlab.ui.container.GridLayout
		DesignTab                       matlab.ui.container.Tab
		GridLayout17_4                  matlab.ui.container.GridLayout
		ContextMenu                     matlab.ui.container.ContextMenu
		sessionssubjecttest02Menu       matlab.ui.container.Menu
		weighingslimit10Menu            matlab.ui.container.Menu
		subjectsMenu                    matlab.ui.container.Menu
	end


	properties (Access = private)
		uiPrefsList cell = {'ScreenEditField','PixelDensitypxcmEditField','DistancecmEditField',...
			'IPAddress','RemoteServerPortEditField',...
			'AudioDeviceEditField','IncorrectBeepEditField','CorrectBeepEditField',...
			'AudioVolumeEditField',...
			'UseALYXSwitch','RewardTimeEditField',...
			'OKResearcher','OKLabName','OKSubject','OKLabLocation',...
			'MaximumSizeEditField','MinimumSizeEditField',...
			'folderEditField','TrialTimeEditField_3',...
			'DeviceNameEditField','TouchDeviceIDEditField',...
			'NegationbufferdegEditField','ExclusionZoneEditField','DrainMultipleEventsCheckBox',...
			'CustomCommandEditField',}
	end

	properties (Access = public)
		jzmq % zeromq connection class
		conductorID = [] % uuid for the conductor from exps endpoint
		alyx % Description
		session % Description
	end

	methods (Access = private)

		function opts = generalOptions(app,opts)
			arguments
				app
				opts = []
			end

			opts.alyx = app.alyx;
			opts.zmq = app.jzmq;
			opts.alyxLink = app.AlyxLink;
			opts.session = [];

			opts.remote = app.RemoteControlSwitch.Value;
			opts.IP = app.IPAddress.Value;
			opts.port = app.RemoteDevicePortEditField.Value;
			opts.screen = app.ScreenEditField.Value;
			opts.distance = app.DistancecmEditField.Value;
			opts.density = app.PixelDensitypxcmEditField.Value;
			opts.bg = eval(app.BackgroundColorEditField.Value);
			opts.smartBackground = app.SmartBackgroundSwitch.Value;

			opts.audio = app.AudioSwitch.Value;
			opts.audioDevice = app.AudioDeviceEditField.Value;
			opts.audioVolume = app.AudioVolumeEditField.Value;
			opts.correctBeep = app.CorrectBeepHzEditField.Value;
			opts.incorrectBeep = app.IncorrectBeepHzEditField.Value;

			opts.folder = app.folderEditField.Value;
			opts.dummy = app.DummyTouchSwitch.Value;
			opts.debug = app.DebugModeSwitch.Value;

			opts.timeOut = app.PunishmentTimeOutEditField.Value;

			GetAlyxSettings(app);
			opts.session = app.session;
			opts.useAlyx = app.PushtoALYXSwitch.Value;
			opts.name = app.OKSubject.Value;
			opts.researcher = app.OKResearcher.Value;
			opts.lab = app.OKLabName.Value;
			opts.location = app.OKLabLocation.Value;

			opts.reward = app.RewardPumpSwitch.Value;
			opts.rewardTime = app.PumpmsEditField.Value;
			opts.rewardPort = '';

			opts.negationBuffer = app.NegationbufferdegEditField.Value;
			opts.exclusionZone = eval(app.ExclusionZoneEditField.Value);
			opts.trackID = logical(app.IgnoreExtraTouchesCheckBox.Value);
			opts.drainEvents = app.DrainMultipleEventsCheckBox.Value;
			opts.verbose = app.VerboseCheckBox.Value;
			opts.strictMode = app.StrictmodeCheckBox.Value;
			opts.doNegation = app.NegateTouchWindowCheckBox.Value;
			opts.touchDevice = app.TouchDeviceIDEditField.Value;
			opts.touchDeviceName = app.TouchDeviceNameEditField.Value;

			opts.phase = 1;
			opts.initSize = 5;
			opts.initPosition = [0 0];
			opts.trialTime = 4;
			opts.stepForward = 10;
			opts.stepBack = 4;
			opts.stepPercent = 0.8;
			opts.delayTime = 0.3;
			opts.sampleTime = 0.3;
			opts.delayDistractors = false;
		end

		function opts = fixOptions(~, opts)
			fn = fieldnames(opts);
			for i = 1:length(fn)
				if ischar(opts.(fn{i})) && matches(opts.(fn{i}),'On')
					opts.(fn{i}) = true;
				elseif ischar(opts.(fn{i})) && matches(opts.(fn{i}),'Off')
					opts.(fn{i}) = false;
				end
			end
		end

		function loadPrefs(app)
			if ~ispref('cagelab'); return; end
			anyLoaded = false; prefnames = ''; a = 1;
			fn = fieldnames(app);
			for i = 1:length(fn)
				prfname = fn{i};
				if ispref('cagelab',prfname) %pref exists
					if isprop(app, prfname) %ui widget exists
						myhandle = app.(prfname);
						prf = getpref('cagelab', prfname);
						uiType = myhandle.Type;
						thisVal = '';
						switch uiType
							case 'uieditfield'
								if ischar(prf)
									myhandle.Value = prf;
									thisVal = prf;
								else
									myhandle.Value = num2str(prf);
									thisVal = myhandle.Value;
								end
							case 'uinumericeditfield'
								myhandle.Value = prf;
								thisVal = num2str(prf);
							case 'uicheckbox'
								if islogical(prf) || isnumeric(prf)
									myhandle.Value = prf;
									thisVal = num2str(prf);
								end
							case 'uidropdown'
								str = myhandle.Items;
								if ischar(prf) && any(contains(prf, str))
									myhandle.Value = prf;
									thisVal = prf;
								elseif iscell(prf) & length(prf)==2
									myhandle.Items = prf{1};
									myhandle.Value = prf{2};
								end
							case 'uimenu'
								myhandle.Checked = prf;
								thisVal = char(prf);
							case 'uirockerswitch'
								if strcmpi(prf,'on') || strcmpi(prf,'off')
									myhandle.Value = prf;
									thisVal = prf;
								end
						end
						prefnames = [prefnames ' ' prfname ' «' thisVal '»'];
						if ~mod(a,3); prefnames = [prefnames '\n']; end
						if ~anyLoaded; anyLoaded = true; end
						a = a + 1;
					end
				end
			end
			if anyLoaded
				fprintf('\n≣≣≣≣⊱  CageLab Load Preferences:\n'); fprintf(prefnames); fprintf('\n');
			end
		end

		function savePrefs(app)
			if ispref('cagelab'); rmpref('cagelab'); end
			anySaved = false; prefnames = ''; a = 1;
			for i = 1:length(app.uiPrefsList)
				prf = [];
				prfname = app.uiPrefsList{i};
				if ~isprop(app,prfname); continue; end
				try
					myhandle = app.(prfname);
					uiType = myhandle.Type;
					switch uiType
						case {'uinumericeditfield','uieditfield','uirockerswitch'}
							prf = myhandle.Value;
						case 'uidropdown'
							prf = {myhandle.Items, myhandle.Value};
						case 'uicheckbox'
							prf = myhandle.Value;
							if ~islogical(prf); prf=logical(prf);end
						case 'uimenu'
							prf = myhandle.Checked;
					end
					if ~isempty(prf)
						setpref('cagelab', prfname, prf);
						prefnames = [prefnames ' ' prfname ' «' num2str(prf) '»'];
						if ~mod(a,3); prefnames = [prefnames '\n']; end
						a = a + 1;
					end
					if ~anySaved; anySaved = true; end
				end
			end
			if anySaved; fprintf('\n≣≣≣≣⊱ CageLab Save Preferences:\n'); fprintf(prefnames); fprintf('\n');end
		end

		function showAlyxTable(app,data)
			tbl = [];
			try
				tbl = struct2table(data,'asArray',true);
			catch ME
				postToLog(app,'Cannot show data as a table...','Warning');
				return
			end
			if ~isempty(tbl)
				app.OKAlyxResults.Data = tbl;
				app.OKAlyxResults.ColumnName = tbl.Properties.VariableNames;
				app.OKAlyxResults.Enable = "on";
			else
				app.OKAlyxResults.Data = table("Unknown error",'VariableNames',{'Error'});
				app.OKAlyxResults.ColumnName = {'Error'};
				app.OKAlyxResults.Enable = "off";
			end
		end

		function checkPasswords(app)
			if strcmpi(app.PushtoALYXSwitch.Value, 'off'); return; end
			secrets = getSecrets(app.alyx);
			if isempty(secrets.password)
				s = '';
				try s = getSecret('AlyxPassword'); end
				if isempty(s); s = getenv('AlyxPassword'); end
				if isempty(s)
					app.alyx.secretUI('password')
				else
					app.alyx.setSecrets(s);
				end
			end
			if isempty(secrets.AWS_ID)
				s = '';
				try s = getSecret('AWS_ID'); end
				if isempty(s); s = getenv('AWS_ID'); end
				if isempty(s)
					app.alyx.secretUI('AWS_ID')
				else
					app.alyx.setSecrets([],s);
				end
			end
			if isempty(secrets.AWS_KEY)
				s = '';
				try s = getSecret('AWS_KEY'); end
				if isempty(s); s = getenv('AWS_KEY'); end
				if isempty(s)
					app.alyx.secretUI('AWS_KEY')
				else
					app.alyx.setSecrets([],[],s);
				end
			end
		end
	end

	methods (Access = public)

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function postToLog(app, msg, prefix)
			if isempty(app.Log.Value)
				app.Log.Value = "=== === Log Start @ "+string(datetime)+" === ===";
			end
			if ~exist('prefix','var'); prefix = []; end
			if isstruct(msg)
				if isfield(msg,'message')
					msg = string(msg.message);
				else
					msg = formattedDisplayText(msg);
				end
			elseif iscell(msg)
				msg = formattedDisplayText(msg);
			else
				msg = string(msg);
			end
			if length(char(msg)) > 800
				msg = char(msg);
				msg = msg(1:800);
				msg = string(msg);
			end
			if ~isempty(prefix)
				app.Log.Value = [app.Log.Value; "---------------------------------------"; prefix; strip(msg,'right')];
			else
				app.Log.Value = [app.Log.Value; "---------------------------------------"; strip(msg,'right')];
			end
			scroll(app.Log,"bottom");
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function [response, data] = sendCmd(app, cmd, data)
			arguments(Input)
				app
				cmd string
				data = []
			end
			apiEndpoint = ['http://' strtok(app.IPAddress.Value)  ':' app.RemoteDevicePortEditField.Value '/api/cmds/proxies/matlab'];
			[response, data] = app.jzmq.sendViaProxy(cmd, data, apiEndpoint);
			postToLog(app, sprintf('Sent Command: %s',cmd));
			postToLog(app, response,"Reply:");
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function response = getEndpoint(app, endpoint, postError)
			arguments(Input)
				app
				endpoint {mustBeNonmissing, mustBeNonempty}
				postError = true
			end
			options = weboptions('ContentType', 'json', 'CharacterEncoding', 'UTF-8');
			apiEndpoint = ['http://' strtok(app.IPAddress.Value)  ':' app.RemoteDevicePortEditField.Value '/' endpoint];
			try
				response = webread(apiEndpoint,options);
				if isstruct(response)
					if isfield(response,'message')
						rt = string(response.message);
					else
						rt = formattedDisplayText(response);
					end
					app.postToLog(string(sprintf('\ngetEndpoint @%s to %s\nRESPONSE:\n%s', string(datetime()), endpoint, rt)));
				end
			catch ME
				response = ME.identifier;
				if postError
					app.postToLog(sprintf('getEndpoint "%s" failed - disable any proxies - error: %s - %s', endpoint, ME.identifier, ME.message));
				end
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function [response, data] = postEndpoint(app, endpoint, data, postError)
			arguments(Input)
				app
				endpoint {mustBeNonmissing, mustBeNonempty}
				data struct = []
				postError = true
			end
			opts = weboptions('MediaType', 'application/json', 'ContentType', 'json', 'CharacterEncoding', 'UTF-8');
			apiEndpoint = ['http://' strtok(app.IPAddress.Value)  ':' app.RemoteDevicePortEditField.Value '/' endpoint];
			try
				response = webwrite(apiEndpoint, data, opts);
				app.postToLog(sprintf('postEndpoint @%s to %s\REPSONSE:\n%s\n', string(datetime()), endpoint, jsonencode(response)));
				if isstruct(response) && isfield(response,'dataType') && matches(response.dataType,'byteStream')
					data = getArrayFromByteStream(uint8(response.data));
					disp(data);
				end
			catch ME
				response = ME.identifier;
				if postError
					app.postToLog(sprintf('postEndpoint "%s" failed - error: %s - %s', endpoint, ME.identifier, ME.message));
				end
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function response = deleteEndpoint(app, endpoint, postError)
			arguments(Input)
				app
				endpoint {mustBeNonmissing, mustBeNonempty}
				postError = true
			end

			opts = matlab.net.http.HTTPOptions;
			opts.ConnectTimeout = 5;
			opts.ResponseTimeout = 10;
			opts.UseProxy = false;

			uri = matlab.net.URI(['http://' strtok(app.IPAddress.Value)  ':' app.RemoteDevicePortEditField.Value]);
			uri.Path = string(endpoint);

			request = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.DELETE);

			try
				response = request.send(uri, opts);
				app.postToLog(sprintf('deleteEndoint to "%s"\nRESPONSE:\n%s\n', endpoint, jsonencode(response)));
			catch ME
				response = ME.identifier;
				if postError
					app.postToLog(sprintf('deleteEndpoint "%s" failed - error: %s - %s', endpoint, ME.identifier, ME.message));
				end
			end
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			try app.loadPrefs(); end
			% p = fileparts(mfilename('fullpath'));
			% if ischar(p) && ~isempty(p)
			% 	app.folderEditField.Value = [p filesep 'resources'];
			% end
			if ismac
				mfont = 'Menlo'; pfont = 'Gill Sans';
			elseif ispc
				mfont = 'Consolas'; pfont = 'Calibri';
			else %linux
				mfont = 'Ubuntu Mono'; pfont = 'Ubuntu';
			end
			fl = listfonts;
			if matches('Graublau Sans',fl)
				pfont = 'Graublau Sans';
			elseif matches('Source Sans 3',fl)
				pfont = 'Source Sans 3';
			elseif matches('Source Sans Pro',fl)
				pfont = 'Source Sans Pro';
			end
			if matches('Fira Code',fl)
				mfont = 'Fira Code';
			elseif matches('Cascadia Code',fl)
				mfont = 'Cascadia Code';
			elseif matches('JetBrains Mono',fl)
				mfont = 'JetBrains Mono';
			end
			try set(groot,'defaultAxesFontName',pfont); end
			pl = properties(app);
			for i = 1:length(pl)
				tg = app.(pl{i});
				if isobject(tg) && isprop(tg,'FontName')
					if contains(class(tg),{'DropDown','EditField','TextArea'},'IgnoreCase',true)
						tg.FontName = mfont;
						try if isprop(tg,'FontSize'); tg.FontSize = 12; end; end
					else
						tg.FontName = pfont;
						try if isprop(tg,'FontSize'); tg.FontSize = 12; end; end
					end
				end
			end
			clear pl lf;

			app.jzmq = jzmqConnection;
			app.alyx = alyxManager;

			if IsLinux
				[~,d]=system('xrandr --screen 0 | grep -E "\\*"');
				if any(contains(d,{'3840','4384','5120','5760','6144'}))
					app.CageLabUIFigure.Position = [20 20 2400 1400];
				end
			end

			app.Log.Value = {['CageLab-GUI V' clutil.version()]};
			app.CageLabUIFigure.Name = ['CageLab-GUI V' clutil.version()];

		end

		% Button pushed function: STARTButton
		function STARTTT(app, event)
			opts = [];
			opts.task = 'train';
			opts.command = 'cltasks.startTouchTraining';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.fg = eval(app.TargetDiscColorEditField.Value);
			opts.stepForward = app.StepForwardNEditField.Value;
			opts.stepPercent = app.PercentCorrectThresholdEditField.Value;
			opts.stepBack = app.StepBackNEditField.Value;
			opts.trialTime = app.TrialTimeEditField.Value;
			opts.maxSize = app.MaximumTargetSizeEditField.Value;
			opts.minSize = app.MinimumTargetSizeEditField.Value;
			opts.taskType = app.DifficultyDropDown.Value;
			opts.stimulus = app.TargetTypeDropDown.Value;
			opts.randomReward = app.RandomRewardTimerEditField.Value;
			opts.randomProbability = app.RandomRewardProbabilityEditField.Value;
			opts.phase = app.Phase120EditField.Value;
			opts.phaseMaxBack = app.MaxPhasesStepBackEditField.Value;
			opts.delayDistractors = false;
			opts.initHoldTime = 0.02;
			opts.targetHoldTime = 0.02;
			opts = fixOptions(app, opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startTouchTraining(opts);
			end
		end

		% Button pushed function: STARTMTSButton
		function STARTMTS(app, event)
			opts = [];
			opts.task = 'mts';
			opts.command = 'cltasks.startMatchToSample';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.taskType = lower(app.TaskStageDropDown.Value);
			opts.sampleY = app.SampleYPositionEditField.Value;
			opts.distractorY = app.DistractorYPositionEditField.Value;
			opts.object = lower(app.ObjectTypeDropDown.Value);
			opts.objectSize = app.ObjectSizeEditField.Value;
			opts.objectSep = app.ObjectSeparationEditField.Value;
			opts.initSize = app.InitiationPointSizeEditField_2.Value;
			opts.initPosition = eval(app.InitiationPointPositionxyEditField_2.Value);
			opts.fg = app.InitiationPointColourEditField_2.Value;
			opts.trialTime = app.TrialTimeEditField_2.Value;
			opts.distractorN = app.NumberofdistractorsEditField.Value;
			opts.delayTime = 0;
			opts.delayDistractors = false;
			opts.initHoldTime = app.InitiationHoldTimeEditField.Value;
			opts.targetHoldTime = app.TargetHoldTimeEditField.Value;
			opts = app.fixOptions(opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startMatchToSample(opts);
			end
		end

		% Button pushed function: STARTDMTSButton
		function STARTDMTS(app, event)
			opts = [];
			opts.task = 'dmts';
			opts.command = 'cltasks.startMatchToSample';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.taskType = lower(app.TaskStageDropDown_4.Value);
			opts.sampleY = app.SampleYPositionEditField_2.Value;
			opts.distractorY = app.DistractorYPositionEditField_2.Value;
			opts.object = lower(app.ObjectTypeDropDown_4.Value);
			opts.objectSize = app.ObjectSizeEditField_3.Value;
			opts.objectSep = app.ObjectSeparationEditField_2.Value;
			opts.initSize = app.InitiationPointSizeEditField_3.Value;
			opts.initPosition = eval(app.InitiationPointPositionxyEditField_3.Value);
			opts.fg = app.InitiationPointColourEditField_3.Value;
			opts.trialTime = app.TrialTimeEditField_8.Value;
			opts.distractorN = app.NumberofdistractorsEditField_2.Value;
			opts.delayTime = eval(app.DelayTimeEditField.Value);
			opts.sampleTime = eval(app.SampleDisplayTimeEditField.Value);
			opts.initHoldTime = app.InitiationHoldTimeEditField_2.Value;
			opts.targetHoldTime = app.TargetHoldTimeEditField_2.Value;
			opts.delayDistractors = app.DelayDistractorsSwitch.Value;
			opts = app.fixOptions(opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startMatchToSample(opts);
			end
		end

		% Button pushed function: STARTPMTButton
		function STARTPMT(app, event)
			opts = [];
			opts.task = 'pacman';
			opts.command = 'PacMan_CageLab';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			[~,opts.mapName] = fileparts(app.MapDropDown.Value);
			opts = fixOptions(app, opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				PacMan_CageLab(opts);
			end
		end

		% Button pushed function: STARTDCATButton
		function STARTDCATB(app, event)
			opts = [];
			opts.task = 'categorisation';
			opts.command = 'cltasks.startDragCategorisation';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.fg = eval(app.InitiationPointColourEditField.Value);
			opts.taskType = app.TaskStageDropDown_3.Value;
			opts.trialTime = app.TrialTimeEditField_7.Value;
			opts.initPosition = eval(app.InitiationPointPositionxyEditField.Value);
			opts.target1Pos = eval(app.TargetPosition1EditField.Value);
			opts.target2Pos = eval(app.TargetPosition2EditField.Value);
			opts.initSize = app.InitiationPointSizeEditField.Value;
			opts.targetSize = app.ObjectSizeEditField_2.Value;
			opts = fixOptions(app, opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startDragCategorisation(opts);
			end
		end

		% Button pushed function: ChangeResourceFolderButton
		function FolderSelection(app, event)
			val = uigetdir;
			if ischar(val)
				app.folderEditField.Value = val;
			end
		end

		% Close request function: CageLabUIFigure
		function CageLabUIFigureCloseRequest(app, event)
			try app.savePrefs(); end %#ok<*TRYNC>
			delete(app)
		end

		% Callback function
		function StartConductorButtonPushed(app, event)
			r = getEndpoint(app,['exps/' app.conductorID], false);
			if isempty(r) || ischar(r)
				data = struct('nickname','conductor','type','git','address',...
					'','exec','matlab -nodesktop -nosplash -r "c = theConductor; run(c)"');
				r = postEndpoint(app, 'exps', data);
			end
			if isstruct(r) && isfield(r,'id')
				app.conductorID = r.id;
				r = getEndpoint(app,['exps/' app.conductorID], false);
				if isstruct(r)
					try
						r = postEndpoint(app,['exps/' app.conductorID '/start']);
					catch ME
						postToLog(app,'Couldn''t start experiment...');
						getReport(ME)
					end
				end
			else
				postToLog(app,'Couldn''t identify the correct exps endpoint');
			end
		end

		% Callback function
		function StopConductorButtonPushed(app, event)
			r = getEndpoint(app,['exps/' app.conductorID], false);
			if isstruct(r) && isfield(r,'id')
				r = deleteEndpoint(app, ['exps/' app.conductorID], false);
			end
		end

		% Button pushed function: PingButton
		function PingButtonPushed(app, event)
			event.Source.Enable = "off"; drawnow;
			try
				if IsWin
					[~,msg] = system(['ping -n 2 ' strtok(app.IPAddress.Value)]);
				else
					[~,msg] = system(['ping -c 2 ' strtok(app.IPAddress.Value)]);
				end
				postToLog(app, string(msg));
			end
			event.Source.Enable = "on";
		end

		% Button pushed function: RunCommandButton
		function RunCommandButtonPushed(app, event)
			event.Source.Enable = "off";
			app.RemoteCommand.Enable = "off"; drawnow;
			cmd = app.RemoteCommand.ValueIndex;
			try
				switch cmd
					case 1
						r = getEndpoint(app, 'api/alive');
					case 2
						r = getEndpoint(app, 'api/device');
					case 3 % get time
						[r,d] = sendCmd(app, 'gettime', struct('currentTime',datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS'),'GetSecs',GetSecs));
						disp(r);
						if isstruct(d)
							d.roundTrip = GetSecs - d.clientGetSecs;
						end
						postToLog(app,d,"Response data:");
					case 4 % custom command
						cmd = app.CustomEditField.Value;
						if ~isempty(cmd)
							response = questdlg('Are You Sure?');
							if matches(response,'Yes')
								[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
								disp(r);
								postToLog(app,d,"Response data:");
							end
						end
					case 5 %get last run
						[r,d] = sendCmd(app, 'getlastrun', []);
						disp(r);
						if isstruct(r) && isfield(r,'command') && matches(r.command,'taskdata')
							postToLog(app,"Will plot the remote data...");
							try
								assignin("base","cagelabData",d);
								d.plotData();
							catch ME
								postToLog(app,"Couldn't plot data...");
							end
						else
							postToLog(app,d);
						end
					case 6 % disable touch screen
						cmd = ['touchManager.enableTouchDevice(''' app.TouchDeviceNameEditField.Value ''', false);'];
						if ~isempty(cmd)
							[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
							disp(r);
							postToLog(app,d,"Response data:");
						end
					case 7 % enable touch screen
						cmd = ['touchManager.enableTouchDevice(''' app.TouchDeviceNameEditField.Value ''', true);'];
						if ~isempty(cmd)
							[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
							disp(r);
							postToLog(app,d,"Response data:");
						end
					case 8 % audio test
						postToLog(app,"You MUST exit sound demo on remote device holding ESC for around 1 seconds");
						response = questdlg("You MUST exit sound demo on remote device holding ESC for around 1 seconds; Are You Sure?");
						if matches(response,'Yes')
							cmd = ['BasicSoundOutputDemo(1, [], ' app.AudioDeviceEditField.Value ')'];
							postToLog(app, ['Run: ' cmd]);
							[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
							disp(r);
							postToLog(app,d,"Response:");
						end
					case 9 % PTB test
						postToLog(app,'Run: VBLSyncTest');
						[r,d] = sendCmd(app, 'run', struct('command','VBLSyncTest','args','none'));
						disp(r);
						postToLog(app,d,"Response data:");
					case 10 % exit server
						[r,d] = sendCmd(app, 'exit', []);
						disp(r);
						postToLog(app,d);
					case 11
						[r,d] = sendCmd(app, 'echo', {'test','data'});
						disp(r);
						postToLog(app,d);
					otherwise

				end
			end
			event.Source.Enable = "on";
			app.RemoteCommand.Enable = "on";
		end

		% Button pushed function: StopButton_5, StopButton_6, 
		% ...and 2 other components
		function StopButton_Pushed(app, event)
			response = questdlg('Are You Sure?');
			if matches(response,'Yes')
				app.sendCmd('exittask',struct('comment','Sending exittask command','timeStamp',GetSecs));
			end
		end

		% Value changed function: RemoteControlSwitch
		function RemoteControlSwitchValueChanged(app, event)
			value = app.RemoteControlSwitch.Value;
			if matches(value,"Off")
				app.RunCommandButton.Enable = "off";
				app.RemoteCommand.Enable = "off";
				app.CustomEditField.Enable = "off";
			else
				app.RunCommandButton.Enable = "on";
				app.RemoteCommand.Enable = "on";
				app.CustomEditField.Enable = "on";
			end
		end

		% Button pushed function: AddIP
		function AddIPPushed(app, event)
			vals = menuN('Add IP Address',{'t|','IP Address:';'t|','Computer Name (optional):'});
			if iscell(vals) && ~isempty(vals{1})
				it = app.IPAddress.Items;
				it = [it [vals{1} '  ' vals{2}]];
				app.IPAddress.Items = it;
			end
		end

		% Button pushed function: RmIP
		function RmIPButtonPushed(app, event)
			it = app.IPAddress.Items;
			val = app.IPAddress.Value;
			if matches(val,'127.0.0.1'); return; end
			idx = matches(it,val);
			it(idx) = [];
			app.IPAddress.Items = it;
		end

		% Callback function
		function AlyxLOGINButtonPushed(app, event)
			app.CageLabUIFigure.Pointer = 'watch';
			app.OKAlyxLOGINButton.Text = '.';drawnow;

			alyx = app.alyx;
			if ~isa(alyx,'alyxManager')
				alyx = alyxManager;
			end
			alyx.user = app.alyxUser.Value;
			alyx.baseURL = app.alyxIP.Value;

			% this checks for AWS_ID, AWS_KEY and AlyxPassword secrets
			% stored locally
			setSecrets(alyx);

			login(alyx);
			WaitSecs(0.1);

			if alyx.loggedIn
				app.OKAlyxLOGINButton.FontColor = [0 0.6 0];
				app.OKAlyxLOGINButton.Text = '..';drawnow;
				try
					procedures = alyx.getData('procedures');
					procedures = {procedures(:).name};
					app.OKAlyxProcedure.Items = procedures;
					idx = find(contains(procedures,'Behavior training'));
					if ~isempty(idx); app.OKAlyxProcedure.ValueIndex = idx(1); end
				end
				app.OKAlyxLOGINButton.Text = '...';drawnow;
				try
					projects = alyx.getData('projects');
					projects = {projects(:).name};
					app.OKAlyxProject.Items = projects;
				end
				app.OKAlyxLOGINButton.Text = '....';drawnow;
				if length(app.OKAlyxBrainRegion.Items) < 10
					try
						regions = alyx.getData('brain-regions');
						regions = {regions(:).name};
						app.OKAlyxBrainRegion.Items = regions;
						idx = find(contains(regions,'Cerebral cortex'));
						if ~isempty(idx); app.OKAlyxBrainRegion.ValueIndex = idx(1); end
					end
				end
				app.OKAlyxLOGINButton.BackgroundColor = [0.8 1 0.8];
				app.OKAlyxLOGINButton.Text = 'LOGGED IN ✔';
				app.OKAlyxRequest.Enable = "on";
				app.OKAlyxRequestButton.Enable = "on";
				app.AlyxAddWeight.Enable = "on";
				app.AlyxAddWater.Enable = "on";
				app.SubjectWeight.Enable = "on";
				app.SubjectWater.Enable = "on";
			else
				warning('Couldn''t login to Alyx, please check network');
				app.OKAlyxLOGINButton.FontColor = [0 0 0];
				app.OKAlyxLOGINButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxLOGINButton.Text = 'LOGIN';
				app.OKAlyxRequest.Enable = "off";
				app.OKAlyxRequestButton.Enable = "off";
				app.AlyxAddWeight.Enable = "off";
				app.AlyxAddWater.Enable = "off";
				app.SubjectWeight.Enable = "off";
				app.SubjectWater.Enable = "off";
			end
			app.CageLabUIFigure.Pointer = 'arrow';
		end

		% Callback function
		function AlyxLOGOUTButtonPushed(app, event)
			if isa(app.alyx,'alyxManager')
				logout(app.alyx);
				app.OKAlyxLOGINButton.FontColor = [0 0 0];
				app.OKAlyxLOGINButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxLOGINButton.Text = 'LOGIN';
				app.OKAlyxRequest.Enable = "off";
				app.OKAlyxRequestButton.Enable = "off";
				app.AlyxAddWeight.Enable = "off";
				app.AlyxAddWater.Enable = "off";
				app.SubjectWeight.Enable = "off";
				app.SubjectWater.Enable = "off";
				app.CageLabUIFigure.Pointer = 'arrow';
			end
		end

		% Callback function
		function AlyxRequestButtonPushed(app, event)
			alyx = app.alyx;
			if isa(alyx,'alyxManager') && alyx.loggedIn
				app.CageLabUIFigure.Pointer = 'watch';
				ic = app.OKAlyxRequestButton.Icon;
				try app.OKAlyxRequestButton.Icon = ''; end
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxRequestButton.FontColor = [0.6 0.6 0.6];
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxRequestButton.Text = '...';drawnow;
				request = app.OKAlyxRequest.Value;
				try
					[data, s] = alyx.getData(request);
					if s == 200 && ~isempty(data) && isstruct(data)
						showAlyxTable(app, data)
					else
						warning('Alyx request returned no data!');
						postToLog(app, 'Alyx request returned no data!')
					end
				end
				WaitSecs('YieldSecs',0.3);
				app.OKAlyxRequestButton.FontColor = [0 0 0];
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				try app.OKAlyxRequestButton.Icon = ic; end
				app.OKAlyxRequestButton.Text = 'Request';
				app.CageLabUIFigure.Pointer = 'arrow';
			end
		end

		% Button pushed function: Button_2, StatusButton, StatusButton_2, 
		% ...and 2 other components
		function StatusButtonPushed(app, event)
			r = getEndpoint(app, 'api/broadcast/data/default/latest');
		end

		% Callback function
		function AlyxAddWeightPushed(app, event)
			if isa(app.alyx,'alyxManager') && app.alyx.loggedIn
				app.CageLabUIFigure.Pointer = 'watch';
				data = [];
				data.weight = str2num(app.SubjectWeight.Value)*1e3;
				data.subject = app.OKSubject.Value;
				data.user = app.alyxUser.Value;
				%[data, statusCode] = postData(me, endpoint, data, requestMethod)
				try
					[ret,st] = app.alyx.postData('weighings',data,'POST');
					postToLog(app,ret,'Result to add new weight:');
				catch ME
					postToLog(app,sprintf('Couldn''t post to database: %s %s', ME.message,ME.identifier));
				end
				try
					[retw, s] = app.alyx.getData(['weighings?subject=' data.subject]);
					if s == 200 && ~isempty(retw) && isstruct(retw)
						showAlyxTable(app, retw)
					else
						warning('Alyx request returned no data!');
						postToLog(app, 'Alyx request returned no data!','Get Weights Error')
					end
				end
				app.CageLabUIFigure.Pointer = 'arrow';
			end
		end

		% Callback function
		function GetAlyxSettings(app, event)
			app.session.subjectName = app.OKSubject.Value;
			app.session.labName = app.OKLabName.Value;
			app.session.researcherName = app.OKResearcher.Value;
			app.session.location = app.OKLabLocation.Value;
			app.session.brainRegion = app.OKAlyxBrainRegion.Value;
			app.session.procedure = app.OKAlyxProcedure.Value;
			app.session.project = app.OKAlyxProject.Value;
			app.session.IP = app.alyxIP.Value;
			app.session.alyxUser = app.alyxUser.Value;
			app.session.useAlyx = app.PushtoALYXSwitch.Value;
			if isempty(app.alyx) || ~isa(app.alyx,'alyxManager')
				app.alyx = alyxManager;
			end
			app.alyx.baseURL = app.session.IP;
			app.alyx.subject = app.session.subjectName;
			app.alyx.user = app.session.alyxUser;
			app.alyx.lab = app.session.labName;
		end

		% Button down function: PacmanTask
		function PacmanTaskButtonDown(app, event)
			dd = [];
			self = fileparts(mfilename("fullpath"));
			ppath = strsplit(self,filesep);
			ppath = strjoin(ppath(1:end-2),filesep);
			ppath = [ppath filesep 'PacmanTask' filesep 'Maps'];
			if exist('PacMan_main','file')
				p = fileparts(which('PacMan_main.m'));
				p = [p filesep 'Maps'];
				dd = dir(p);
			elseif exist(ppath,'dir')
				dd = dir(ppath);
			end
			if ~isempty(dd)
				af = @(in) contains(in.name,'Generate');
				idx = arrayfun(af,dd);
				maps = {dd(idx).name};
				app.MapDropDown.Items = maps;
			end
		end

		% Button pushed function: HideDesktopButton
		function HideDesktopButtonPushed(app, event)
			[r,d] = sendCmd(app, 'hidedesktop', []);
			disp(r);
			postToLog(app,d,"Hide Desktop Response:");
		end

		% Button pushed function: ShowDesktopButton
		function ShowDesktopButtonPushed(app, event)
			[r,d] = sendCmd(app, 'showdesktop', []);
			disp(r);
			postToLog(app,d,"Show Desktop Response:");
		end

		% Button pushed function: PowerDownButton
		function PowerDownButtonPushed(app, event)
			response = questdlg('Power OFF CageLab!?!');
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!poweroff','args','none'));
				disp(r);
				postToLog(app,d,"Power Off Response:");
			end
		end

		% Button pushed function: ResetMATLABButton
		function ResetMATLABButtonPushed(app, event)
			response = questdlg('Restart Remote MATLAB?');
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!zsh ~/Code/CageLab/software/scripts/cagelab-reset.sh','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: ResetOBSButton
		function ResetOBSButtonPushed(app, event)
			response = questdlg('Restart Streaming?');
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!zsh ~/Code/CageLab/software/scripts/cagelab-reset-OBS.sh','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: DisableSleepButton
		function DisableSleepButtonPushed(app, event)
			response = questdlg('Disable Screen Sleep?');
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!xset -dpms s off','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: ChangePWButton
		function ChangePWButtonPushed(app, event)
			value = app.PasswordReset.Value;
			if contains(value,'Alyx')
				setSecret('AlyxPassword','Overwrite',true);
			elseif contains(value,'ID')
				setSecret('AWS_ID','Overwrite',true);
			elseif contains(value,'KEY')
				setSecret('AWS_KEY','Overwrite',true);
			end
		end

		% Button pushed function: EnableSleepButton
		function EnableSleepButtonPushed(app, event)
			response = questdlg('Enable Screen Sleep?');
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!xset +dpms s 600','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.CageLabUIFigure = uifigure('Visible', 'off');
			app.CageLabUIFigure.Name = 'CageLab';
			app.CageLabUIFigure.Icon = fullfile(pathToMLAPP, 'ui', 'cagelab.png');
			app.CageLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @CageLabUIFigureCloseRequest, true);
			app.GridLayoutMAIN = uigridlayout(app.CageLabUIFigure);
			app.TabGroup = uitabgroup(app.GridLayoutMAIN);
			app.MainTab = uitab(app.TabGroup);
			app.MainTab.Title = '⚙️ Main';
			app.GridLayout = uigridlayout(app.MainTab);
			app.GlobalSettingsPanel = uipanel(app.GridLayout);
			app.GlobalSettingsPanel.Title = 'Global Settings';
			app.GridLayout2 = uigridlayout(app.GlobalSettingsPanel);
			app.ScreenEditFieldLabel = uilabel(app.GridLayout2);
			app.ScreenEditFieldLabel.Text = 'Screen';
			app.ScreenEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistancecmEditFieldLabel = uilabel(app.GridLayout2);
			app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
			app.DistancecmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistancecmEditField.Limits = [1 200];
			app.DistancecmEditField.Value = 25;
			app.BackgroundColorEditFieldLabel = uilabel(app.GridLayout2);
			app.BackgroundColorEditFieldLabel.Text = 'Background Color';
			app.BackgroundColorEditField = uieditfield(app.GridLayout2, 'text');
			app.BackgroundColorEditField.Value = '[ 0.5 0.5 0.5 ]';
			app.PixelDensitypxcmEditFieldLabel = uilabel(app.GridLayout2);
			app.PixelDensitypxcmEditFieldLabel.Text = 'Pixel Density (px/cm)';
			app.PixelDensitypxcmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PixelDensitypxcmEditField.Value = 88;
			app.CageLabRemoteControlPanel = uipanel(app.GridLayout2);
			app.CageLabRemoteControlPanel.Title = 'CageLab Remote Control';
			app.GridLayout4 = uigridlayout(app.CageLabRemoteControlPanel);
			app.RemoteDevicePortEditFieldLabel = uilabel(app.GridLayout4);
			app.RemoteDevicePortEditFieldLabel.Text = 'Remote Device Port';
			app.RemoteDevicePortEditField = uieditfield(app.GridLayout4, 'text');
			app.RemoteDevicePortEditField.Value = '9012';
			app.RemoteControlSwitchLabel = uilabel(app.GridLayout4);
			app.RemoteControlSwitchLabel.Text = '🎛️ Remote Control';
			app.RemoteControlSwitch = uiswitch(app.GridLayout4, 'slider');
			app.RemoteControlSwitch.ValueChangedFcn = createCallbackFcn(app, @RemoteControlSwitchValueChanged, true);
			app.RemoteControlSwitch.Value = 'On';
			app.PingButton = uibutton(app.GridLayout4, 'push');
			app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
			app.PingButton.Icon = fullfile(pathToMLAPP, 'ui', 'pin.svg');
			app.PingButton.Tooltip = {'Ping Remote Device'};
			app.PingButton.Text = '';
			app.RunCommandButton = uibutton(app.GridLayout4, 'push');
			app.RunCommandButton.ButtonPushedFcn = createCallbackFcn(app, @RunCommandButtonPushed, true);
			app.RunCommandButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.RunCommandButton.Text = 'Run Command ⤵';
			app.RemoteCommand = uilistbox(app.GridLayout4);
			app.RemoteCommand.Items = {'cogmoteGO: Check if cogmoteGO is Alive?', 'cogmoteGO: Get Remote Device Info', 'Conductor: Test Roundtrip Time Sync', 'Conductor: RUN Custom Command', 'Conductor: Retieve and Plot Data for Last Session', 'Conductor: Disable Remote Touch Screen', 'Conductor: Enable Remote Touch Screen', 'Conductor: Audio Test', 'Conductor: PTB VBL Test', 'Conductor: Exit Conductor Server Loop'};
			app.RemoteCommand.Value = 'cogmoteGO: Check if cogmoteGO is Alive?';
			app.IPAddress = uidropdown(app.GridLayout4);
			app.IPAddress.Items = {'127.0.0.1'};
			app.IPAddress.Tooltip = {'The remote system IP address'};
			app.IPAddress.Value = '127.0.0.1';
			app.RemoteServerPortEditFieldLabel_2 = uilabel(app.GridLayout4);
			app.RemoteServerPortEditFieldLabel_2.Text = 'Remote Device Address';
			app.AddIP = uibutton(app.GridLayout4, 'push');
			app.AddIP.ButtonPushedFcn = createCallbackFcn(app, @AddIPPushed, true);
			app.AddIP.Icon = fullfile(pathToMLAPP, 'ui', 'plus.svg');
			app.AddIP.Tooltip = {'Add'};
			app.AddIP.Text = '';
			app.RmIP = uibutton(app.GridLayout4, 'push');
			app.RmIP.ButtonPushedFcn = createCallbackFcn(app, @RmIPButtonPushed, true);
			app.RmIP.Icon = fullfile(pathToMLAPP, 'ui', 'minus.svg');
			app.RmIP.Tooltip = {'Remove'};
			app.RmIP.Text = '';
			app.CustomEditField = uieditfield(app.GridLayout4, 'text');
			app.CustomEditField.Placeholder = 'custom command';
			app.AlyxLink = uihyperlink(app.GridLayout4);
			app.AlyxLink.Enable = 'off';
			app.AlyxLink.Text = '...Last Session URL...';
			app.GridLayout18 = uigridlayout(app.GridLayout4);
			app.WakeUpButton = uibutton(app.GridLayout18, 'push');
			app.WakeUpButton.Text = 'Wake Up';
			app.HideDesktopButton = uibutton(app.GridLayout18, 'push');
			app.HideDesktopButton.ButtonPushedFcn = createCallbackFcn(app, @HideDesktopButtonPushed, true);
			app.HideDesktopButton.Text = 'Hide Desktop';
			app.ResetMATLABButton = uibutton(app.GridLayout18, 'push');
			app.ResetMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMATLABButtonPushed, true);
			app.ResetMATLABButton.Text = 'Reset-MATLAB';
			app.ResetOBSButton = uibutton(app.GridLayout18, 'push');
			app.ResetOBSButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOBSButtonPushed, true);
			app.ResetOBSButton.Text = 'Reset-OBS';
			app.PowerDownButton = uibutton(app.GridLayout18, 'push');
			app.PowerDownButton.ButtonPushedFcn = createCallbackFcn(app, @PowerDownButtonPushed, true);
			app.PowerDownButton.Text = 'Power Down';
			app.ShowDesktopButton = uibutton(app.GridLayout18, 'push');
			app.ShowDesktopButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDesktopButtonPushed, true);
			app.ShowDesktopButton.Text = 'Show Desktop';
			app.DisableSleepButton = uibutton(app.GridLayout18, 'push');
			app.DisableSleepButton.ButtonPushedFcn = createCallbackFcn(app, @DisableSleepButtonPushed, true);
			app.DisableSleepButton.Text = 'Disable Sleep';
			app.EnableSleepButton = uibutton(app.GridLayout18, 'push');
			app.EnableSleepButton.ButtonPushedFcn = createCallbackFcn(app, @EnableSleepButtonPushed, true);
			app.EnableSleepButton.Text = 'Enable Sleep';
			app.Button_2 = uibutton(app.GridLayout4, 'push');
			app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
			app.Button_2.Tag = 'touchTraining';
			app.Button_2.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.Button_2.Tooltip = {'Get remote Session Status'};
			app.Button_2.Text = '';
			app.SmartBackgroundLabel = uilabel(app.GridLayout2);
			app.SmartBackgroundLabel.Text = '🌫️ Smart Background';
			app.SmartBackgroundSwitch = uiswitch(app.GridLayout2, 'slider');
			app.SmartBackgroundSwitch.Value = 'On';
			app.DebugModeLabel = uilabel(app.GridLayout2);
			app.DebugModeLabel.Text = '🔧 Debug Mode';
			app.DebugModeSwitch = uiswitch(app.GridLayout2, 'slider');
			app.AudioLabel = uilabel(app.GridLayout2);
			app.AudioLabel.Text = '👂🏼 Audio';
			app.AudioSwitch = uiswitch(app.GridLayout2, 'slider');
			app.AudioSwitch.Value = 'On';
			app.PushtoALYXLabel = uilabel(app.GridLayout2);
			app.PushtoALYXLabel.Text = '🗃️ Push to ALYX';
			app.PushtoALYXSwitch = uiswitch(app.GridLayout2, 'slider');
			app.ChangeResourceFolderButton = uibutton(app.GridLayout2, 'push');
			app.ChangeResourceFolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelection, true);
			app.ChangeResourceFolderButton.Icon = fullfile(pathToMLAPP, 'ui', 'folder-outline.svg');
			app.ChangeResourceFolderButton.Text = 'Change Resource Folder';
			app.folderEditField = uieditfield(app.GridLayout2, 'text');
			app.folderEditField.Tooltip = {'Folder where task resources are stored'};
			app.folderEditField.Value = '~/Code/CageLab/software/resources';
			app.AudioVolumeEditFieldLabel = uilabel(app.GridLayout2);
			app.AudioVolumeEditFieldLabel.Text = 'Audio Volume';
			app.AudioVolumeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.AudioVolumeEditField.Limits = [0 1];
			app.AudioVolumeEditField.Value = 1;
			app.PunishmentTimeOutEditFieldLabel = uilabel(app.GridLayout2);
			app.PunishmentTimeOutEditFieldLabel.Text = 'Punishment Time Out';
			app.PunishmentTimeOutEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PunishmentTimeOutEditField.Limits = [0 100];
			app.PunishmentTimeOutEditField.Value = 5;
			app.Label = uilabel(app.GridLayout2);
			app.Label.Text = 'Correct Beep (Hz)';
			app.CorrectBeepHzEditField = uieditfield(app.GridLayout2, 'numeric');
			app.CorrectBeepHzEditField.Limits = [20 20000];
			app.CorrectBeepHzEditField.Value = 1800;
			app.IncorrectBeepHzEditFieldLabel = uilabel(app.GridLayout2);
			app.IncorrectBeepHzEditFieldLabel.Text = 'Incorrect Beep (Hz)';
			app.IncorrectBeepHzEditField = uieditfield(app.GridLayout2, 'numeric');
			app.IncorrectBeepHzEditField.Limits = [20 20000];
			app.IncorrectBeepHzEditField.Value = 300;
			app.DummyTouchLabel = uilabel(app.GridLayout2);
			app.DummyTouchLabel.Text = '🖱️ Dummy Touch';
			app.DummyTouchSwitch = uiswitch(app.GridLayout2, 'slider');
			app.RewardPumpLabel = uilabel(app.GridLayout2);
			app.RewardPumpLabel.Text = '💦 Reward Pump';
			app.RewardPumpSwitch = uiswitch(app.GridLayout2, 'slider');
			app.RewardPumpSwitch.Value = 'On';
			app.AudioDeviceEditFieldLabel = uilabel(app.GridLayout2);
			app.AudioDeviceEditFieldLabel.Text = 'Audio Device';
			app.AudioDeviceEditField = uieditfield(app.GridLayout2, 'text');
			app.AudioDeviceEditField.Value = '[ ]';
			app.PumpmsEditFieldLabel = uilabel(app.GridLayout2);
			app.PumpmsEditFieldLabel.Text = 'Pump (ms)';
			app.PumpmsEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PumpmsEditField.Limits = [0 1000];
			app.PumpmsEditField.RoundFractionalValues = 'on';
			app.PumpmsEditField.Value = 200;
			app.TouchScreenDefaultsPanel = uipanel(app.GridLayout2);
			app.TouchScreenDefaultsPanel.Title = 'Touch Screen Defaults';
			app.GridLayout10 = uigridlayout(app.TouchScreenDefaultsPanel);
			app.TouchDeviceIDEditFieldLabel = uilabel(app.GridLayout10);
			app.TouchDeviceIDEditFieldLabel.Text = 'Touch Device ID';
			app.TouchDeviceIDEditField = uieditfield(app.GridLayout10, 'numeric');
			app.TouchDeviceIDEditField.AllowEmpty = 'on';
			app.TouchDeviceIDEditField.Tooltip = {'Most systems should leave this at 1'};
			app.TouchDeviceIDEditField.Placeholder = '1';
			app.TouchDeviceIDEditField.Value = 1;
			app.NegateTouchWindowCheckBox = uicheckbox(app.GridLayout10);
			app.NegateTouchWindowCheckBox.Text = 'Negate Touch Window';
			app.NegateTouchWindowCheckBox.Value = true;
			app.StrictmodeCheckBox = uicheckbox(app.GridLayout10);
			app.StrictmodeCheckBox.Text = 'Strict mode';
			app.StrictmodeCheckBox.Value = true;
			app.DrainMultipleEventsCheckBox = uicheckbox(app.GridLayout10);
			app.DrainMultipleEventsCheckBox.Text = 'Drain Multiple Events?';
			app.IgnoreExtraTouchesCheckBox = uicheckbox(app.GridLayout10);
			app.IgnoreExtraTouchesCheckBox.Text = 'Ignore Extra Touches?';
			app.IgnoreExtraTouchesCheckBox.Value = true;
			app.ExclusionZoneEditFieldLabel = uilabel(app.GridLayout10);
			app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
			app.ExclusionZoneEditField = uieditfield(app.GridLayout10, 'text');
			app.ExclusionZoneEditField.Value = '[ ]';
			app.NegationbufferdegEditFieldLabel = uilabel(app.GridLayout10);
			app.NegationbufferdegEditFieldLabel.Text = 'Negation buffer (deg)';
			app.NegationbufferdegEditField = uieditfield(app.GridLayout10, 'numeric');
			app.NegationbufferdegEditField.Value = 2;
			app.TouchDeviceNameEditFieldLabel = uilabel(app.GridLayout10);
			app.TouchDeviceNameEditFieldLabel.Text = 'Touch Device Name';
			app.TouchDeviceNameEditField = uieditfield(app.GridLayout10, 'text');
			app.TouchDeviceNameEditField.Tooltip = {'The name of the touch device as seen in `xinput list`'};
			app.TouchDeviceNameEditField.Placeholder = 'ILITEK-TP';
			app.TouchDeviceNameEditField.Value = 'ILITEK-TP';
			app.VerboseCheckBox = uicheckbox(app.GridLayout10);
			app.VerboseCheckBox.Text = 'Verbose';
			app.AlyxTab = uitab(app.TabGroup);
			app.AlyxTab.Title = '🗃️ Alyx';
			app.GridLayout11 = uigridlayout(app.AlyxTab);
			app.ALYXInterfacePanel = uipanel(app.GridLayout11);
			app.ALYXInterfacePanel.Title = 'ALYX Interface';
			app.AlyxLayout = uigridlayout(app.ALYXInterfacePanel);
			app.text4554 = uilabel(app.AlyxLayout);
			app.text4554.Text = 'ALYX IP & Port';
			app.text4554_2 = uilabel(app.AlyxLayout);
			app.text4554_2.Text = 'ALYX User';
			app.OKAlyxResults = uitable(app.AlyxLayout);
			app.OKAlyxResults.ColumnName = {'ID'; 'Start'; 'End'; 'Lab'};
			app.OKAlyxResults.RowName = {};
			app.OKAlyxRequestButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxRequestButton.Icon = fullfile(pathToMLAPP, 'ui', 'send.svg');
			app.OKAlyxRequestButton.Enable = 'off';
			app.OKAlyxRequestButton.Text = 'Request';
			app.OKAlyxLOGOUTButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGOUTButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.off.svg');
			app.OKAlyxLOGOUTButton.Text = 'Log out';
			app.OKAlyxLOGINButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGINButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.svg');
			app.OKAlyxLOGINButton.Text = 'Login';
			app.OKAlyxRequest = uieditfield(app.AlyxLayout, 'text');
			app.OKAlyxRequest.Enable = 'off';
			app.OKAlyxRequest.Value = 'sessions?subject=test02';
			app.OKAlyxProcedure = uidropdown(app.AlyxLayout);
			app.OKAlyxProcedure.Items = {'Behavior training/tasks', 'Imaging', 'Intraocular injection', 'Optical stimulation'};
			app.OKAlyxProcedure.Value = 'Behavior training/tasks';
			app.OKAlyxProject = uidropdown(app.AlyxLayout);
			app.OKAlyxProject.Items = {'Default'};
			app.OKAlyxProject.Value = 'Default';
			app.text4554_3 = uilabel(app.AlyxLayout);
			app.text4554_3.Text = 'Project';
			app.OKAlyxBrainRegion = uidropdown(app.AlyxLayout);
			app.OKAlyxBrainRegion.Items = {'Cerebral cortex', 'Thalamus', 'Hippocampus', 'Basal Ganglia'};
			app.OKAlyxBrainRegion.Value = 'Cerebral cortex';
			app.text4554_4 = uilabel(app.AlyxLayout);
			app.text4554_4.Text = 'Brain Region';
			app.TaskProtocolEditFieldLabel = uilabel(app.AlyxLayout);
			app.TaskProtocolEditFieldLabel.Text = 'Task Protocol';
			app.OKAlyxTaskProtocol = uieditfield(app.AlyxLayout, 'text');
			app.alyxIP = uieditfield(app.AlyxLayout, 'text');
			app.alyxIP.Value = 'http://172.16.102.30:8000';
			app.alyxUser = uieditfield(app.AlyxLayout, 'text');
			app.alyxUser.Value = 'admin';
			app.ProcedureLabel = uilabel(app.AlyxLayout);
			app.ProcedureLabel.Text = 'Procedure';
			app.OKSubject = uieditfield(app.AlyxLayout, 'text');
			app.OKSubject.Tag = 'OKTrainingName';
			app.OKSubject.Tooltip = {'Unique Subject Name / ID'};
			app.OKSubject.Value = 'Simulcra';
			app.text209 = uilabel(app.AlyxLayout);
			app.text209.Tag = 'text209';
			app.text209.Text = {'Subject ID'; '🧠'};
			app.OKResearcher = uieditfield(app.AlyxLayout, 'text');
			app.OKResearcher.Tag = 'OKTrainingResearcherName';
			app.OKResearcher.Tooltip = {'Experimenter name'};
			app.OKResearcher.Value = 'Jane Doe';
			app.text4554_5 = uilabel(app.AlyxLayout);
			app.text4554_5.Tag = 'text4554';
			app.text4554_5.Text = {'Researcher'; '🧑🏻‍🔬'};
			app.OKLabName = uieditfield(app.AlyxLayout, 'text');
			app.OKLabName.Tag = 'OKTrainingName';
			app.OKLabName.Tooltip = {'Lab / Group name'};
			app.OKLabName.Value = 'Lab';
			app.text209_2 = uilabel(app.AlyxLayout);
			app.text209_2.Tag = 'text209';
			app.text209_2.Text = {'Lab Name'; '🥼'};
			app.OKLabLocation = uieditfield(app.AlyxLayout, 'text');
			app.OKLabLocation.Tag = 'OKTrainingResearcherName';
			app.OKLabLocation.Tooltip = {'Room / building where experiment is being performed'};
			app.OKLabLocation.Value = 'Room 10';
			app.text4554_6 = uilabel(app.AlyxLayout);
			app.text4554_6.Tag = 'text4554';
			app.text4554_6.Text = {'Experiment Location'; '🗺️'};
			app.QueryAlyxDatabaseLabel = uilabel(app.AlyxLayout);
			app.QueryAlyxDatabaseLabel.Text = 'Query Alyx Database:';
			app.SubjectWeight = uieditfield(app.AlyxLayout, 'text');
			app.SubjectWeight.Tag = 'OKTrainingName';
			app.SubjectWeight.Enable = 'off';
			app.SubjectWeight.Tooltip = {'Unique Subject Name / ID'};
			app.SubjectWeight.Placeholder = '...weight in kg';
			app.AlyxAddWeight = uibutton(app.AlyxLayout, 'push');
			app.AlyxAddWeight.Icon = fullfile(pathToMLAPP, 'ui', 'plus.svg');
			app.AlyxAddWeight.Enable = 'off';
			app.AlyxAddWeight.Text = 'Weight';
			app.SubjectWater = uieditfield(app.AlyxLayout, 'text');
			app.SubjectWater.Tag = 'OKTrainingName';
			app.SubjectWater.Enable = 'off';
			app.SubjectWater.Tooltip = {'Unique Subject Name / ID'};
			app.SubjectWater.Placeholder = '...water given';
			app.AlyxAddWater = uibutton(app.AlyxLayout, 'push');
			app.AlyxAddWater.Icon = fullfile(pathToMLAPP, 'ui', 'plus.svg');
			app.AlyxAddWater.Enable = 'off';
			app.AlyxAddWater.Text = 'Water';
			app.SessionInformationLabel = uilabel(app.AlyxLayout);
			app.SessionInformationLabel.Text = 'Session Information:';
			app.PasswordReset = uidropdown(app.AlyxLayout);
			app.PasswordReset.Items = {'New Alyx Password', 'New AWS ID', 'New AWS Key'};
			app.PasswordReset.Value = 'New Alyx Password';
			app.ChangePWButton = uibutton(app.AlyxLayout, 'push');
			app.ChangePWButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePWButtonPushed, true);
			app.ChangePWButton.Text = 'Change PW';
			app.TouchTab = uitab(app.TabGroup);
			app.TouchTab.Title = '👆🏻 Touch';
			app.GridLayout12 = uigridlayout(app.TouchTab);
			app.AutomatedTouchTrainingPanel = uipanel(app.GridLayout12);
			app.AutomatedTouchTrainingPanel.Title = 'Automated Touch Training';
			app.GridLayout9 = uigridlayout(app.AutomatedTouchTrainingPanel);
			app.Phase120EditFieldLabel = uilabel(app.GridLayout9);
			app.Phase120EditFieldLabel.Text = 'Phase (1-20)';
			app.Phase120EditField = uieditfield(app.GridLayout9, 'numeric');
			app.Phase120EditField.Limits = [1 20];
			app.Phase120EditField.Value = 1;
			app.StepForwardNEditFieldLabel = uilabel(app.GridLayout9);
			app.StepForwardNEditFieldLabel.Text = 'Step Forward N';
			app.StepForwardNEditField = uieditfield(app.GridLayout9, 'numeric');
			app.StepForwardNEditField.Value = 10;
			app.MaximumTargetSizeEditFieldLabel = uilabel(app.GridLayout9);
			app.MaximumTargetSizeEditFieldLabel.Text = 'Maximum Target Size';
			app.MaximumTargetSizeEditField = uieditfield(app.GridLayout9, 'numeric');
			app.MaximumTargetSizeEditField.Value = 45;
			app.MinimumTargetSizeEditFieldLabel = uilabel(app.GridLayout9);
			app.MinimumTargetSizeEditFieldLabel.Text = 'Minimum Target Size';
			app.MinimumTargetSizeEditField = uieditfield(app.GridLayout9, 'numeric');
			app.MinimumTargetSizeEditField.Value = 5;
			app.RandomRewardTimerEditFieldLabel = uilabel(app.GridLayout9);
			app.RandomRewardTimerEditFieldLabel.Text = 'Random Reward Timer';
			app.RandomRewardTimerEditField = uieditfield(app.GridLayout9, 'numeric');
			app.RandomRewardTimerEditField.Value = 15;
			app.RandomRewardProbabilityEditFieldLabel = uilabel(app.GridLayout9);
			app.RandomRewardProbabilityEditFieldLabel.Text = 'Random Reward Probability';
			app.RandomRewardProbabilityEditField = uieditfield(app.GridLayout9, 'numeric');
			app.RandomRewardProbabilityEditField.Limits = [0 1];
			app.RandomRewardProbabilityEditField.Value = 0.25;
			app.TargetDiscColorEditFieldLabel = uilabel(app.GridLayout9);
			app.TargetDiscColorEditFieldLabel.Text = 'Target (Disc) Color';
			app.TargetDiscColorEditField = uieditfield(app.GridLayout9, 'text');
			app.TargetDiscColorEditField.Value = '[ 1 0.9 0.3 ]';
			app.TrialTimeEditFieldLabel = uilabel(app.GridLayout9);
			app.TrialTimeEditFieldLabel.Text = 'Trial Time';
			app.TrialTimeEditField = uieditfield(app.GridLayout9, 'numeric');
			app.TrialTimeEditField.Value = 5;
			app.DifficultyDropDownLabel = uilabel(app.GridLayout9);
			app.DifficultyDropDownLabel.Text = 'Difficulty';
			app.DifficultyDropDown = uidropdown(app.GridLayout9);
			app.DifficultyDropDown.Items = {'Simple', 'Normal'};
			app.DifficultyDropDown.Value = 'Simple';
			app.TargetTypeDropDownLabel = uilabel(app.GridLayout9);
			app.TargetTypeDropDownLabel.Text = 'Target Type';
			app.TargetTypeDropDown = uidropdown(app.GridLayout9);
			app.TargetTypeDropDown.Items = {'Picture', 'Disc'};
			app.TargetTypeDropDown.Value = 'Picture';
			app.STARTButton = uibutton(app.GridLayout9, 'push');
			app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTT, true);
			app.STARTButton.Tag = 'touchTraining';
			app.STARTButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTButton.Text = 'START';
			app.StopButton_5 = uibutton(app.GridLayout9, 'push');
			app.StopButton_5.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Pushed, true);
			app.StopButton_5.Tag = 'touchTraining';
			app.StopButton_5.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_5.Text = 'Stop';
			app.StepBackNEditFieldLabel = uilabel(app.GridLayout9);
			app.StepBackNEditFieldLabel.Text = 'Step Back N ';
			app.StepBackNEditField = uieditfield(app.GridLayout9, 'numeric');
			app.StepBackNEditField.Value = 40;
			app.PercentCorrectThresholdEditFieldLabel = uilabel(app.GridLayout9);
			app.PercentCorrectThresholdEditFieldLabel.Text = 'Percent Correct Threshold';
			app.PercentCorrectThresholdEditField = uieditfield(app.GridLayout9, 'numeric');
			app.PercentCorrectThresholdEditField.Value = 0.8;
			app.RandomrewardsonlyoccurinPhase1to3andthisencouragesthemonkeytocomeforwardsandengagewithCageLabIfthemonkeyisnotengagingeveryXsecondsarewardwillbegivenwithYprobabilityLabel = uilabel(app.GridLayout9);
			app.RandomrewardsonlyoccurinPhase1to3andthisencouragesthemonkeytocomeforwardsandengagewithCageLabIfthemonkeyisnotengagingeveryXsecondsarewardwillbegivenwithYprobabilityLabel.Text = 'Random rewards only occur in Phase 1 to 3, and this encourages the monkey to come forwards and engage with CageLab. If the monkey is not engaging, every X seconds a reward will be given with Y probability.';
			app.StatusButton = uibutton(app.GridLayout9, 'push');
			app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
			app.StatusButton.Tag = 'touchTraining';
			app.StatusButton.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton.Text = 'Status?';
			app.MaxPhasesStepBackEditFieldLabel = uilabel(app.GridLayout9);
			app.MaxPhasesStepBackEditFieldLabel.Text = 'Max Phases Step Back';
			app.MaxPhasesStepBackEditField = uieditfield(app.GridLayout9, 'numeric');
			app.MaxPhasesStepBackEditField.Value = 1;
			app.MTSTab = uitab(app.TabGroup);
			app.MTSTab.Title = '🔍 MTS';
			app.GridLayout13 = uigridlayout(app.MTSTab);
			app.MatchToSampleMTSPanel = uipanel(app.GridLayout13);
			app.MatchToSampleMTSPanel.Title = 'Match To Sample (MTS)';
			app.GridLayout9_2 = uigridlayout(app.MatchToSampleMTSPanel);
			app.NumberofdistractorsEditFieldLabel = uilabel(app.GridLayout9_2);
			app.NumberofdistractorsEditFieldLabel.Text = 'Number of distractors';
			app.NumberofdistractorsEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.NumberofdistractorsEditField.Limits = [0 4];
			app.NumberofdistractorsEditField.Value = 2;
			app.ObjectSizeEditFieldLabel = uilabel(app.GridLayout9_2);
			app.ObjectSizeEditFieldLabel.Text = 'Object Size';
			app.ObjectSizeEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.ObjectSizeEditField.Value = 8;
			app.ObjectSeparationEditFieldLabel = uilabel(app.GridLayout9_2);
			app.ObjectSeparationEditFieldLabel.Text = 'Object Separation';
			app.ObjectSeparationEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.ObjectSeparationEditField.Value = 10;
			app.TrialTimeEditField_2Label = uilabel(app.GridLayout9_2);
			app.TrialTimeEditField_2Label.Text = 'Trial Time';
			app.TrialTimeEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.TrialTimeEditField_2.Value = 5;
			app.TaskStageDropDownLabel = uilabel(app.GridLayout9_2);
			app.TaskStageDropDownLabel.Text = 'Task Stage';
			app.TaskStageDropDown = uidropdown(app.GridLayout9_2);
			app.TaskStageDropDown.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStageDropDown.Value = 'Full Task';
			app.ObjectTypeDropDownLabel = uilabel(app.GridLayout9_2);
			app.ObjectTypeDropDownLabel.Text = 'Object Type';
			app.ObjectTypeDropDown = uidropdown(app.GridLayout9_2);
			app.ObjectTypeDropDown.Items = {'Quaddles', 'Fractals', 'Flowers'};
			app.ObjectTypeDropDown.Value = 'Quaddles';
			app.STARTMTSButton = uibutton(app.GridLayout9_2, 'push');
			app.STARTMTSButton.ButtonPushedFcn = createCallbackFcn(app, @STARTMTS, true);
			app.STARTMTSButton.Tag = 'touchTraining';
			app.STARTMTSButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTMTSButton.Text = 'START MTS';
			app.StopButton_6 = uibutton(app.GridLayout9_2, 'push');
			app.StopButton_6.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Pushed, true);
			app.StopButton_6.Tag = 'touchTraining';
			app.StopButton_6.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_6.Text = 'Stop';
			app.InitiationPointPositionxyEditField_2Label = uilabel(app.GridLayout9_2);
			app.InitiationPointPositionxyEditField_2Label.Text = 'Initiation Point Position (x,y)';
			app.InitiationPointPositionxyEditField_2 = uieditfield(app.GridLayout9_2, 'text');
			app.InitiationPointPositionxyEditField_2.Value = '[ 0 6 ]';
			app.InitiationPointColourEditField_2Label = uilabel(app.GridLayout9_2);
			app.InitiationPointColourEditField_2Label.Text = 'Initiation Point Colour';
			app.InitiationPointColourEditField_2 = uieditfield(app.GridLayout9_2, 'text');
			app.InitiationPointColourEditField_2.Value = '[ 1 0.4 0.3 ]';
			app.InitiationPointSizeEditField_2Label = uilabel(app.GridLayout9_2);
			app.InitiationPointSizeEditField_2Label.Text = 'Initiation Point Size';
			app.InitiationPointSizeEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.InitiationPointSizeEditField_2.Value = 6;
			app.SampleYPositionEditFieldLabel = uilabel(app.GridLayout9_2);
			app.SampleYPositionEditFieldLabel.Text = 'Sample Y Position';
			app.SampleYPositionEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.SampleYPositionEditField.Value = -9;
			app.DistractorYPositionEditFieldLabel = uilabel(app.GridLayout9_2);
			app.DistractorYPositionEditFieldLabel.Text = 'Distractor Y Position';
			app.DistractorYPositionEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.DistractorYPositionEditField.Value = -1;
			app.InitiationHoldTimeEditFieldLabel = uilabel(app.GridLayout9_2);
			app.InitiationHoldTimeEditFieldLabel.Text = 'Initiation Hold Time';
			app.InitiationHoldTimeEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.InitiationHoldTimeEditField.Value = 0.03;
			app.TargetHoldTimeEditFieldLabel = uilabel(app.GridLayout9_2);
			app.TargetHoldTimeEditFieldLabel.Text = 'Target Hold Time';
			app.TargetHoldTimeEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.TargetHoldTimeEditField.Value = 0.05;
			app.StatusButton_2 = uibutton(app.GridLayout9_2, 'push');
			app.StatusButton_2.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
			app.StatusButton_2.Tag = 'touchTraining';
			app.StatusButton_2.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_2.Text = 'Status?';
			app.DMTSTab = uitab(app.TabGroup);
			app.DMTSTab.Title = '⏸️🔍 DMTS';
			app.GridLayout14 = uigridlayout(app.DMTSTab);
			app.DelayedMatchToSampleMTSPanel = uipanel(app.GridLayout14);
			app.DelayedMatchToSampleMTSPanel.Title = 'Delayed Match To Sample (MTS)';
			app.GridLayout9_5 = uigridlayout(app.DelayedMatchToSampleMTSPanel);
			app.NumberofdistractorsEditField_2Label = uilabel(app.GridLayout9_5);
			app.NumberofdistractorsEditField_2Label.Text = 'Number of distractors';
			app.NumberofdistractorsEditField_2 = uieditfield(app.GridLayout9_5, 'numeric');
			app.NumberofdistractorsEditField_2.Limits = [0 4];
			app.NumberofdistractorsEditField_2.Value = 4;
			app.ObjectSizeEditField_3Label = uilabel(app.GridLayout9_5);
			app.ObjectSizeEditField_3Label.Text = 'Object Size';
			app.ObjectSizeEditField_3 = uieditfield(app.GridLayout9_5, 'numeric');
			app.ObjectSizeEditField_3.Value = 8;
			app.ObjectSeparationEditField_2Label = uilabel(app.GridLayout9_5);
			app.ObjectSeparationEditField_2Label.Text = 'Object Separation';
			app.ObjectSeparationEditField_2 = uieditfield(app.GridLayout9_5, 'numeric');
			app.ObjectSeparationEditField_2.Value = 10;
			app.TrialTimeEditField_8Label = uilabel(app.GridLayout9_5);
			app.TrialTimeEditField_8Label.Text = 'Trial Time';
			app.TrialTimeEditField_8 = uieditfield(app.GridLayout9_5, 'numeric');
			app.TrialTimeEditField_8.Value = 5;
			app.TaskStageDropDown_4Label = uilabel(app.GridLayout9_5);
			app.TaskStageDropDown_4Label.Text = 'Task Stage';
			app.TaskStageDropDown_4 = uidropdown(app.GridLayout9_5);
			app.TaskStageDropDown_4.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStageDropDown_4.Value = 'Full Task';
			app.ObjectTypeDropDown_4Label = uilabel(app.GridLayout9_5);
			app.ObjectTypeDropDown_4Label.Text = 'Object Type';
			app.ObjectTypeDropDown_4 = uidropdown(app.GridLayout9_5);
			app.ObjectTypeDropDown_4.Items = {'Quaddles', 'Fractals', 'Flowers'};
			app.ObjectTypeDropDown_4.Value = 'Quaddles';
			app.STARTDMTSButton = uibutton(app.GridLayout9_5, 'push');
			app.STARTDMTSButton.ButtonPushedFcn = createCallbackFcn(app, @STARTDMTS, true);
			app.STARTDMTSButton.Tag = 'touchTraining';
			app.STARTDMTSButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTDMTSButton.Text = 'START DMTS';
			app.StopButton_9 = uibutton(app.GridLayout9_5, 'push');
			app.StopButton_9.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Pushed, true);
			app.StopButton_9.Tag = 'touchTraining';
			app.StopButton_9.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_9.Text = 'Stop';
			app.InitiationPointPositionxyEditField_3Label = uilabel(app.GridLayout9_5);
			app.InitiationPointPositionxyEditField_3Label.Text = 'Initiation Point Position (x,y)';
			app.InitiationPointPositionxyEditField_3 = uieditfield(app.GridLayout9_5, 'text');
			app.InitiationPointPositionxyEditField_3.Value = '[ 0 6 ]';
			app.InitiationPointColourEditField_3Label = uilabel(app.GridLayout9_5);
			app.InitiationPointColourEditField_3Label.Text = 'Initiation Point Colour';
			app.InitiationPointColourEditField_3 = uieditfield(app.GridLayout9_5, 'text');
			app.InitiationPointColourEditField_3.Value = '[ 1 0.4 0.3 ]';
			app.InitiationPointSizeEditField_3Label = uilabel(app.GridLayout9_5);
			app.InitiationPointSizeEditField_3Label.Text = 'Initiation Point Size';
			app.InitiationPointSizeEditField_3 = uieditfield(app.GridLayout9_5, 'numeric');
			app.InitiationPointSizeEditField_3.Value = 6;
			app.SampleYPositionEditField_2Label = uilabel(app.GridLayout9_5);
			app.SampleYPositionEditField_2Label.Text = 'Sample Y Position';
			app.SampleYPositionEditField_2 = uieditfield(app.GridLayout9_5, 'numeric');
			app.SampleYPositionEditField_2.Value = -9;
			app.DistractorYPositionEditField_2Label = uilabel(app.GridLayout9_5);
			app.DistractorYPositionEditField_2Label.Text = 'Distractor Y Position';
			app.DistractorYPositionEditField_2 = uieditfield(app.GridLayout9_5, 'numeric');
			app.DistractorYPositionEditField_2.Value = -1;
			app.DelayTimeEditFieldLabel = uilabel(app.GridLayout9_5);
			app.DelayTimeEditFieldLabel.Text = 'Delay Time';
			app.DelayTimeEditField = uieditfield(app.GridLayout9_5, 'text');
			app.DelayTimeEditField.Value = '[ 0.2 0.4 ]';
			app.DelayDistractorsSwitchLabel = uilabel(app.GridLayout9_5);
			app.DelayDistractorsSwitchLabel.Text = 'Delay Distractors?';
			app.DelayDistractorsSwitch = uiswitch(app.GridLayout9_5, 'slider');
			app.SampleDisplayTimeEditFieldLabel = uilabel(app.GridLayout9_5);
			app.SampleDisplayTimeEditFieldLabel.Text = 'Sample Display Time';
			app.SampleDisplayTimeEditField = uieditfield(app.GridLayout9_5, 'text');
			app.SampleDisplayTimeEditField.Value = '0.3';
			app.InitiationHoldTimeEditField_2Label = uilabel(app.GridLayout9_5);
			app.InitiationHoldTimeEditField_2Label.Text = 'Initiation Hold Time';
			app.InitiationHoldTimeEditField_2 = uieditfield(app.GridLayout9_5, 'numeric');
			app.InitiationHoldTimeEditField_2.Value = 0.03;
			app.TargetHoldTimeEditField_2Label = uilabel(app.GridLayout9_5);
			app.TargetHoldTimeEditField_2Label.Text = 'Target Hold Time';
			app.TargetHoldTimeEditField_2 = uieditfield(app.GridLayout9_5, 'numeric');
			app.TargetHoldTimeEditField_2.Value = 0.05;
			app.StatusButton_3 = uibutton(app.GridLayout9_5, 'push');
			app.StatusButton_3.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
			app.StatusButton_3.Tag = 'touchTraining';
			app.StatusButton_3.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_3.Text = 'Status?';
			app.PacmanTask = uitab(app.TabGroup);
			app.PacmanTask.Title = '🕹️ Pacman';
			app.PacmanTask.ButtonDownFcn = createCallbackFcn(app, @PacmanTaskButtonDown, true);
			app.GridLayout15 = uigridlayout(app.PacmanTask);
			app.PacmanTaskPMTPanel = uipanel(app.GridLayout15);
			app.PacmanTaskPMTPanel.Title = '🕹️ Pacman Task (PMT)';
			app.GridLayout9_6 = uigridlayout(app.PacmanTaskPMTPanel);
			app.MapLabel = uilabel(app.GridLayout9_6);
			app.MapLabel.Text = '🗺️ Map ';
			app.MapDropDown = uidropdown(app.GridLayout9_6);
			app.MapDropDown.Items = {'GenerateRandomMap_1_T_random_dir', 'GenerateRandomMap_L_simple'};
			app.MapDropDown.Value = 'GenerateRandomMap_1_T_random_dir';
			app.STARTPMTButton = uibutton(app.GridLayout9_6, 'push');
			app.STARTPMTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTPMT, true);
			app.STARTPMTButton.Tag = 'touchTraining';
			app.STARTPMTButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTPMTButton.Text = 'START  PMT';
			app.StopButton_10 = uibutton(app.GridLayout9_6, 'push');
			app.StopButton_10.Tag = 'touchTraining';
			app.StopButton_10.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_10.Text = 'Stop';
			app.StatusButton_5 = uibutton(app.GridLayout9_6, 'push');
			app.StatusButton_5.Tag = 'touchTraining';
			app.StatusButton_5.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_5.Text = 'Status?';
			app.NotethePacmantaskusesitsowndisplaysettingsandsotheMaingeneraldisplaysettingsdonotapplytothistaskYoumusteditthePacmancodeitselftomakeadjustmentsforthemomentThisinterfaceallowsyoutolaunchthePacmantaskandsetthemaptobetestedandalsoconfiguretheAlyxdatasettingsLabel = uilabel(app.GridLayout9_6);
			app.NotethePacmantaskusesitsowndisplaysettingsandsotheMaingeneraldisplaysettingsdonotapplytothistaskYoumusteditthePacmancodeitselftomakeadjustmentsforthemomentThisinterfaceallowsyoutolaunchthePacmantaskandsetthemaptobetestedandalsoconfiguretheAlyxdatasettingsLabel.Text = 'Note: the Pacman task uses its own display settings and so the [⚙️ Main] general display settings do not apply to this task. You must edit the Pacman code itself to make adjustments for the moment... This interface allows you to launch the Pacman task and set the map to be tested, and also configure the Alyx data settings.';
			app.GoukiCATTab = uitab(app.TabGroup);
			app.GoukiCATTab.Title = '🐃 Gouki-CAT';
			app.GridLayout16 = uigridlayout(app.GoukiCATTab);
			app.DragCategorisationDCATPanel = uipanel(app.GridLayout16);
			app.DragCategorisationDCATPanel.Title = 'Drag Categorisation (DCAT)';
			app.GridLayout9_4 = uigridlayout(app.DragCategorisationDCATPanel);
			app.InterTrialIntervalEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InterTrialIntervalEditFieldLabel.Text = 'Inter Trial Interval';
			app.InterTrialIntervalEditField = uieditfield(app.GridLayout9_4, 'numeric');
			app.InterTrialIntervalEditField.Value = 2;
			app.InitiationPointPositionxyEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InitiationPointPositionxyEditFieldLabel.Text = 'Initiation Point Position (x,y)';
			app.InitiationPointPositionxyEditField = uieditfield(app.GridLayout9_4, 'text');
			app.InitiationPointPositionxyEditField.Value = '[ 0 6 ]';
			app.TrialTimeEditField_7Label = uilabel(app.GridLayout9_4);
			app.TrialTimeEditField_7Label.Text = 'Trial Time';
			app.TrialTimeEditField_7 = uieditfield(app.GridLayout9_4, 'numeric');
			app.TrialTimeEditField_7.Value = 5;
			app.TaskStageDropDown_3Label = uilabel(app.GridLayout9_4);
			app.TaskStageDropDown_3Label.Text = 'Task Stage';
			app.TaskStageDropDown_3 = uidropdown(app.GridLayout9_4);
			app.TaskStageDropDown_3.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStageDropDown_3.Value = 'Full Task';
			app.ObjectTypeDropDown_3Label = uilabel(app.GridLayout9_4);
			app.ObjectTypeDropDown_3Label.Text = 'Object Type';
			app.ObjectTypeDropDown_3 = uidropdown(app.GridLayout9_4);
			app.ObjectTypeDropDown_3.Items = {'Animal/Object'};
			app.ObjectTypeDropDown_3.Value = 'Animal/Object';
			app.STARTDCATButton = uibutton(app.GridLayout9_4, 'push');
			app.STARTDCATButton.ButtonPushedFcn = createCallbackFcn(app, @STARTDCATB, true);
			app.STARTDCATButton.Tag = 'touchTraining';
			app.STARTDCATButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTDCATButton.Text = 'START  DCAT';
			app.InitiationPointColourEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InitiationPointColourEditFieldLabel.Text = 'Initiation Point Colour';
			app.InitiationPointColourEditField = uieditfield(app.GridLayout9_4, 'text');
			app.InitiationPointColourEditField.Value = '[ 1 0.4 0.3 ]';
			app.TargetPosition1EditFieldLabel = uilabel(app.GridLayout9_4);
			app.TargetPosition1EditFieldLabel.Text = 'Target Position 1';
			app.TargetPosition1EditField = uieditfield(app.GridLayout9_4, 'text');
			app.TargetPosition1EditField.Value = '[ -9 -6 ]';
			app.TargetPosition2EditFieldLabel = uilabel(app.GridLayout9_4);
			app.TargetPosition2EditFieldLabel.Text = 'Target Position 2';
			app.TargetPosition2EditField = uieditfield(app.GridLayout9_4, 'text');
			app.TargetPosition2EditField.Value = '[ 9 -6 ]';
			app.ObjectSizeEditField_2Label = uilabel(app.GridLayout9_4);
			app.ObjectSizeEditField_2Label.Text = 'Object Size';
			app.ObjectSizeEditField_2 = uieditfield(app.GridLayout9_4, 'numeric');
			app.ObjectSizeEditField_2.Value = 8;
			app.InitiationPointSizeEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InitiationPointSizeEditFieldLabel.Text = 'Initiation Point Size';
			app.InitiationPointSizeEditField = uieditfield(app.GridLayout9_4, 'numeric');
			app.InitiationPointSizeEditField.Value = 6;
			app.StopButton_8 = uibutton(app.GridLayout9_4, 'push');
			app.StopButton_8.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Pushed, true);
			app.StopButton_8.Tag = 'touchTraining';
			app.StopButton_8.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_8.Text = 'Stop';
			app.StatusButton_4 = uibutton(app.GridLayout9_4, 'push');
			app.StatusButton_4.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
			app.StatusButton_4.Tag = 'touchTraining';
			app.StatusButton_4.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_4.Text = 'Status?';
			app.ICATab = uitab(app.TabGroup);
			app.ICATab.Title = '🐄 ICA';
			app.GridLayout17 = uigridlayout(app.ICATab);
			app.PatchForagingTab = uitab(app.TabGroup);
			app.PatchForagingTab.Title = '🎋 Patch Foraging';
			app.GridLayout17_2 = uigridlayout(app.PatchForagingTab);
			app.ThresholdsTab = uitab(app.TabGroup);
			app.ThresholdsTab.Title = '💡  Thresholds';
			app.GridLayout17_3 = uigridlayout(app.ThresholdsTab);
			app.DesignTab = uitab(app.TabGroup);
			app.DesignTab.Title = '📑 Design';
			app.GridLayout17_4 = uigridlayout(app.DesignTab);
			app.GridLayoutLOG = uigridlayout(app.GridLayoutMAIN);
			app.Log = uitextarea(app.GridLayoutLOG);
			app.Log.Placeholder = 'Log';
			app.Image = uiimage(app.GridLayoutLOG);
			app.Image.ImageSource = fullfile(pathToMLAPP, 'ui', 'cagelab.png');
			app.ContextMenu = uicontextmenu(app.CageLabUIFigure);
			app.sessionssubjecttest02Menu = uimenu(app.ContextMenu);
			app.sessionssubjecttest02Menu.Text = 'sessions?subject=test02';
			app.weighingslimit10Menu = uimenu(app.ContextMenu);
			app.weighingslimit10Menu.Text = 'weighings?limit=10';
			app.subjectsMenu = uimenu(app.ContextMenu);
			app.subjectsMenu.Text = 'subjects';
			% Assign app.ContextMenu
			app.OKAlyxRequest.ContextMenu = app.ContextMenu;
			% Show the figure after all components are created
			app.CageLabUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = CageLab

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CageLabUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CageLabUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CageLabUIFigure)
		end
	end
end